classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IntroTab                        matlab.ui.container.Tab
        HardDiskDriveProjectLabel       matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        HTab                            matlab.ui.container.Tab
        TextArea_3Label                 matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        Hinf_SintesiButton              matlab.ui.control.Button
        Hinf_AnalisidiRobustaStabilitLabel  matlab.ui.control.Label
        Hinf_RobustAnalysisButton       matlab.ui.control.Button
        Hinf_AnalisidellarispostainfrequenzaimpiantononscalatoLabel  matlab.ui.control.Label
        Hinf_RispostainfrequenzaButton  matlab.ui.control.Button
        Hinf_AnalisidellarispostatemporaleimpiantoLabel  matlab.ui.control.Label
        Hinf_RispostaTemporaleButton    matlab.ui.control.Button
        OrdinedelcontrolloreEditField_10Label  matlab.ui.control.Label
        Hinf_OrdinedelcontrolloreEditField  matlab.ui.control.NumericEditField
        Hinf_VerificaSpecificheButton   matlab.ui.control.Button
        NominaleStabilitLampLabel       matlab.ui.control.Label
        Hinf_NSLamp                     matlab.ui.control.Lamp
        RobustaStabilitLabel            matlab.ui.control.Label
        Hinf_RSLamp                     matlab.ui.control.Lamp
        NominalePrestazioneLabel        matlab.ui.control.Label
        Hinf_NPLamp                     matlab.ui.control.Lamp
        RobustaPrestazioneLabel         matlab.ui.control.Label
        Hinf_RPLamp                     matlab.ui.control.Lamp
        Hinf_RSLabel                    matlab.ui.control.Label
        Hinf_NPLabel                    matlab.ui.control.Label
        Hinf_RPLabel                    matlab.ui.control.Label
        Hinf_VerificadelleSpecificheLabel  matlab.ui.control.Label
        Hinf_AnalisidiRobustaStabilitLabel2  matlab.ui.control.Label
        Hinf_FunzioniPesoLabel          matlab.ui.control.Label
        Hinf_FunzioniPesoButton         matlab.ui.control.Button
        LSDPHTab                        matlab.ui.container.Tab
        TextArea_5Label                 matlab.ui.control.Label
        TextArea_5                      matlab.ui.control.TextArea
        LSDP_SintesiButton              matlab.ui.control.Button
        LSDP_AnalisidiRobustaStabilitLabel  matlab.ui.control.Label
        LSDP_RobustAnalysisButton       matlab.ui.control.Button
        LSDP_AnalisidellarispostainfrequenzaimpiantononscalatoLabel  matlab.ui.control.Label
        LSDP_RispostainfrequenzaButton  matlab.ui.control.Button
        LSDP_AnalisidellarispostatemporaleimpiantoLabel  matlab.ui.control.Label
        LSDP_RispostaTemporaleButton    matlab.ui.control.Button
        LSDP_VerificaSpecificheButton   matlab.ui.control.Button
        LSDP_RSLabel                    matlab.ui.control.Label
        LSDP_NPLabel                    matlab.ui.control.Label
        LSDP_RPLabel                    matlab.ui.control.Label
        LSDP_VerificadelleSpecificheLabel  matlab.ui.control.Label
        LSDP_AnalisidiRobustaStabilitLabel2  matlab.ui.control.Label
        OrdinedelcontrolloreEditField_17Label  matlab.ui.control.Label
        LSDP_OrdinedelcontrolloreEditField  matlab.ui.control.NumericEditField
        Hinf_NSLamp_2Label              matlab.ui.control.Label
        LSDP_NSLamp                     matlab.ui.control.Lamp
        Hinf_RSLamp_2Label              matlab.ui.control.Label
        LSDP_RSLamp                     matlab.ui.control.Lamp
        Hinf_NPLamp_2Label              matlab.ui.control.Label
        LSDP_NPLamp                     matlab.ui.control.Lamp
        Hinf_RPLamp_2Label              matlab.ui.control.Label
        LSDP_RPLamp                     matlab.ui.control.Lamp
        Tab                             matlab.ui.container.Tab
        TextArea_4Label                 matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        mu_SintesiButton                matlab.ui.control.Button
        mu_AnalisidiRobustaStabilitLabel  matlab.ui.control.Label
        mu_RobustAnalysisButton         matlab.ui.control.Button
        mu_AnalisidellarispostainfrequenzaimpiantononscalatoLabel  matlab.ui.control.Label
        mu_RispostainfrequenzaButton    matlab.ui.control.Button
        mu_AnalisidellarispostatemporaleimpiantoLabel  matlab.ui.control.Label
        mu_RispostaTemporaleButton      matlab.ui.control.Button
        mu_VerificaSpecificheButton     matlab.ui.control.Button
        mu_RSLabel                      matlab.ui.control.Label
        mu_NPLabel                      matlab.ui.control.Label
        mu_RPLabel                      matlab.ui.control.Label
        mu_VerificadelleSpecificheLabel  matlab.ui.control.Label
        mu_AnalisidiRobustaStabilitLabel2  matlab.ui.control.Label
        mu_FunzioniPesoLabel            matlab.ui.control.Label
        mu_FunzioniPesoButton           matlab.ui.control.Button
        Hinf_NSLamp_2Label_2            matlab.ui.control.Label
        mu_NSLamp                       matlab.ui.control.Lamp
        Hinf_RSLamp_2Label_2            matlab.ui.control.Label
        mu_RSLamp                       matlab.ui.control.Lamp
        Hinf_NPLamp_2Label_2            matlab.ui.control.Label
        mu_NPLamp                       matlab.ui.control.Lamp
        Hinf_RPLamp_2Label_2            matlab.ui.control.Label
        mu_RPLamp                       matlab.ui.control.Lamp
        LSDP_OrdinedelcontrolloreEditField_2Label  matlab.ui.control.Label
        mu_OrdinedelcontrolloreEditField  matlab.ui.control.NumericEditField
        ClassicoTab_2                   matlab.ui.container.Tab
        TextArea_6Label                 matlab.ui.control.Label
        TextArea_6                      matlab.ui.control.TextArea
        SintesidelcontrolloreLabel_7    matlab.ui.control.Label
        SintesiPIDButton                matlab.ui.control.Button
        ImpostaiseguentiparametriLabel_2  matlab.ui.control.Label
        AnalisidiRobustaStabilitLabel_12  matlab.ui.control.Label
        RobustAnalysisButton_5          matlab.ui.control.Button
        AnalisidellarispostainfrequenzaimpiantononscalatoLabel_12  matlab.ui.control.Label
        FrequencyResponseButton_5       matlab.ui.control.Button
        AnalisidellarispostatemporaleimpiantoperturbatoscalatoLabel_5  matlab.ui.control.Label
        TimeResponseButton_5            matlab.ui.control.Button
        radsLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        VerificheButton_4               matlab.ui.control.Button
        AMPEditFieldLabel               matlab.ui.control.Label
        AMPEditField                    matlab.ui.control.NumericEditField
        STEditFieldLabel                matlab.ui.control.Label
        STEditField                     matlab.ui.control.NumericEditField
        OrdinedelcontrolloreEditField_14Label  matlab.ui.control.Label
        OrdinedelcontrolloreEditField_14  matlab.ui.control.NumericEditField
        NominaleStabilitEditField_14Label  matlab.ui.control.Label
        NominaleStabilitEditField_14    matlab.ui.control.EditField
        WcEditField                     matlab.ui.control.NumericEditField
        OSEditField                     matlab.ui.control.NumericEditField
        PID_SintesiButton               matlab.ui.control.Button
        PID_AnalisidiRobustaStabilitLabel  matlab.ui.control.Label
        PID_RobustAnalysisButton        matlab.ui.control.Button
        PID_AnalisidellarispostainfrequenzaimpiantononscalatoLabel  matlab.ui.control.Label
        PID_RispostainfrequenzaButton   matlab.ui.control.Button
        PID_AnalisidellarispostatemporaleimpiantoLabel  matlab.ui.control.Label
        PID_RispostaTemporaleButton     matlab.ui.control.Button
        PID_VerificaSpecificheButton    matlab.ui.control.Button
        PID_VerificadelleSpecificheLabel  matlab.ui.control.Label
        PID_AnalisidiRobustaStabilitLabel2  matlab.ui.control.Label
        mu_NSLamp_2Label                matlab.ui.control.Label
        PID_NSLamp                      matlab.ui.control.Lamp
        mu_RSLamp_2Label                matlab.ui.control.Label
        PID_RSLamp                      matlab.ui.control.Lamp
        mu_NPLamp_2Label                matlab.ui.control.Label
        PID_NPLamp                      matlab.ui.control.Lamp
        mu_RPLamp_2Label                matlab.ui.control.Label
        PID_RPLamp                      matlab.ui.control.Lamp
        mu_OrdinedelcontrolloreEditField_2Label  matlab.ui.control.Label
        PID_OrdinedelcontrolloreEditField  matlab.ui.control.NumericEditField
        PID_RSLabel                     matlab.ui.control.Label
        PID_NPLabel                     matlab.ui.control.Label
        PID_RPLabel                     matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function ParameterDefinition(app)
            % campi GUI
            %app.WcEditField.Value = 100*2*pi*1e3; % [rad/s]    %0.5
            %app.OSEditField.Value = 20; %    %15
            %app.AMPEditField.Value = 20; % dB     %2
            %app.STEditField.Value = 1.5; % [ms]    %40 min
            %app.qEditField_2.Value = 1e09; % valore recupero default LTR_u
            %app.qEditField_3.Value = 1e09; % % valore recupero default LTR_y 
            
            
            sys_ic = evalin('base','sys_ic');


            %app.WaterDensityEditField.Value = evalin('base','rho_water');
            %app.AmplitudeEditField.Value = evalin('base','wave_amplitude');
            
            
            % Execute the startup function
            %runStartupFcn(app, @startupFcn)
        end

        % Callback function
        function DOFDropDownValueChanged(app, event)
            dof_hin = evalin('base','dof_hin');
            switch app.DOFDropDown.Value
                case '1'
                    dof_hin = 0;
                case '2'
                    dof_hin = 1;
            end
            assignin('base','dof_hin', dof_hin);
        end

        % Callback function
        function frspweigthfuncSwitchValueChanged(app, event)
            weight_frsp_flag = evalin('base','weight_frsp_flag');
            switch app.frspweigthfuncSwitch.Value
                case 'Off'
                    weight_frsp_flag = 0;
                    %app.frspweigthfuncSwitch_2.Value = 'Off';
                    app.frspweigthfuncSwitch_3.Value = 'Off';
                case 'On'
                    weight_frsp_flag = 1;
                    %app.frspweigthfuncSwitch_2.Value = 'On';
                    app.frspweigthfuncSwitch_3.Value = 'On';
            end
            assignin('base','weight_frsp_flag', weight_frsp_flag);
        end

        % Button pushed function: Hinf_SintesiButton
        function Hinf_SintesiButtonPushed(app, event)
            global K_hin;
            
            %K = K_hin;
            
            sys_ic = evalin('base','sys_ic');
            
            
            app.Hinf_SintesiButton.Enable = 'off';
            app.Hinf_SintesiButton.Text = 'Sto sintetizzando...';
            
            hinf_hdd;
            K = K_hin;
            
            %           Aggiorno campo 'Nominale Stabilità
            CL_Nominal = clp_hin;
            NS = isstable(CL_Nominal);
            
            if (NS == 1)
                app.Hinf_NSLamp.Color = 'green';
            else
                app.Hinf_NSLamp.Color = 'red';
            end
            
            %           Aggiorno campo 'ordine del controllore'
            [m,n]=size(K.A);
            app.Hinf_OrdinedelcontrolloreEditField.Value = m;
            
            app.Hinf_SintesiButton.Text = 'Sintesi H∞';
            app.Hinf_SintesiButton.Enable = 'on';
        end

        % Button pushed function: Hinf_RobustAnalysisButton
        function Hinf_RobustAnalysisButtonPushed(app, event)
            global K_hin
            
            sys_ic = evalin('base','sys_ic');
            
            app.Hinf_RobustAnalysisButton.Enable = 'off';
            app.Hinf_RobustAnalysisButton.Text = 'Sto eseguendo...';
            
            K = K_hin;
            [stabmarg, perfmarg] = mu_hdd(sys_ic, K, 'K_hin');
            %sv = evalin('base','sv');
            clp_ic = lft(sys_ic,K,1,1);
            %w = logspace(3,5,100);
            w = logspace(2,5,100);
            sv = sigma(clp_ic.Nominal,w);
            
            RS = stabmarg.LowerBound;
            app.Hinf_RSLabel.Text = [num2str(RS*100), ' %'];
            if (RS > 1)
                app.Hinf_RSLamp.Color = 'green';
            else
                app.Hinf_RSLamp.Color = 'red';
            end
            
            NP = max(sv(1,:));
            app.Hinf_NPLabel.Text = [num2str(100/NP), ' %'];
            if (NP < 1)
                app.Hinf_NPLamp.Color = 'green';
            else
                app.Hinf_NPLamp.Color = 'red';
            end
            
            RP = perfmarg.LowerBound;
            app.Hinf_RPLabel.Text = [num2str(RP*100), ' %'];
            if (RP > 1)
                app.Hinf_RPLamp.Color = 'green';
            else
                app.Hinf_RPLamp.Color = 'red';
            end
            
            app.Hinf_RobustAnalysisButton.Text = 'Robust Analysis';
            app.Hinf_RobustAnalysisButton.Enable = 'on';
            
        end

        % Button pushed function: Hinf_RispostainfrequenzaButton
        function Hinf_RispostainfrequenzaButtonPushed(app, event)
            global K_hin;
            
            app.Hinf_RispostainfrequenzaButton.Enable = 'off';
            app.Hinf_RispostainfrequenzaButton.Text = 'Sto eseguendo...';
            
            K = K_hin;
            frs_hdd;
            
            app.Hinf_RispostainfrequenzaButton.Text = 'Frequency Response';
            app.Hinf_RispostainfrequenzaButton.Enable = 'on';
            
        end

        % Button pushed function: Hinf_RispostaTemporaleButton
        function Hinf_RispostaTemporaleButtonPushed(app, event)
            global K_hin;
            
            app.Hinf_RispostaTemporaleButton.Enable = 'off';
            app.Hinf_RispostaTemporaleButton.Text = 'Sto eseguendo...';
            
            K = K_hin;
            mcs_hdd;
            
            app.Hinf_RispostaTemporaleButton.Text = 'Time Response';
            app.Hinf_RispostaTemporaleButton.Enable = 'on';
            
        end

        % Callback function
        function DOFDropDown_2ValueChanged(app, event)
            dof_h2 = evalin('base','dof_h2');
            switch app.DOFDropDown_2.Value
                case '1'
                    dof_h2 = 0;
                case '2'
                    dof_h2 = 1;
            end
            assignin('base','dof_h2', dof_h2);
        end

        % Callback function
        function frspweigthfuncSwitch_2ValueChanged(app, event)
            weight_frsp_flag = evalin('base','weight_frsp_flag');
            switch app.frspweigthfuncSwitch_2.Value
                case 'Off'
                    weight_frsp_flag = 0;
                    app.frspweigthfuncSwitch.Value = 'Off';
                    app.frspweigthfuncSwitch_3.Value = 'Off';
                case 'On'
                    weight_frsp_flag = 1;
                    app.frspweigthfuncSwitch.Value = 'On';
                    app.frspweigthfuncSwitch_3.Value = 'On';
            end
            assignin('base','weight_frsp_flag', weight_frsp_flag);
        end

        % Callback function
        function SintesiH2ButtonPushed(app, event)
            
        end

        % Callback function
        function RobustAnalysisButton_2Pushed(app, event)
            global K_h2
            
            sys_ic = evalin('base','sys_ic');
            
            app.RobustAnalysisButton_2.Enable = 'off';
            app.RobustAnalysisButton_2.Text = 'Sto eseguendo...';
            
            K = K_h2;
            mu_hdd;
            
            app.RobustAnalysisButton_2.Text = 'Robust Analysis';
            app.RobustAnalysisButton_2.Enable = 'on';
            
        end

        % Callback function
        function FrequencyResponseButton_2Pushed(app, event)
            global K_h2;
            
            app.FrequencyResponseButton_2.Enable = 'off';
            app.FrequencyResponseButton_2.Text = 'Sto eseguendo...';
            
            K = K_h2;
            frs_hdd;
            
            app.FrequencyResponseButton_2.Text = 'Frequency Response';
            app.FrequencyResponseButton_2.Enable = 'on';
            
        end

        % Callback function
        function TimeResponseButton_2Pushed(app, event)
            global K_h2;
            
            app.TimeResponseButton_2.Enable = 'off';
            app.TimeResponseButton_2.Text = 'Sto eseguendo...';
            
            K = K_h2;
            mcs_hdd;
            
            app.TimeResponseButton_2.Text = 'Time Response';
            app.TimeResponseButton_2.Enable = 'on';
            
        end

        % Callback function
        function DOFDropDown_3ValueChanged(app, event)
            dof_mu = evalin('base','dof_mu');
            switch app.DOFDropDown_3.Value
                case '1'
                    dof_mu = 0;
                case '2'
                    dof_mu = 1;
            end
            assignin('base','dof_mu', dof_mu);
        end

        % Callback function
        function frspweigthfuncSwitch_3ValueChanged(app, event)
            weight_frsp_flag = evalin('base','weight_frsp_flag');
            switch app.frspweigthfuncSwitch_3.Value
                case 'Off'
                    weight_frsp_flag = 0;
                    app.frspweigthfuncSwitch.Value = 'Off';
                    %app.frspweigthfuncSwitch_2.Value = 'Off';
                case 'On'
                    weight_frsp_flag = 1;
                    app.frspweigthfuncSwitch.Value = 'On';
                    %app.frspweigthfuncSwitch_2.Value = 'On';
            end
            assignin('base','weight_frsp_flag', weight_frsp_flag);
        end

        % Callback function
        function SintesiButtonPushed(app, event)
            global K; global K_mu;
            
            sys_ic = evalin('base','sys_ic');
            
            app.SintesiButton.Enable = 'off';
            app.SintesiButton.Text = 'Sto sintetizzando...';
            
            ms_hdd;
            
            %           Aggiorno campo 'Nominale Stabilità
            CL_Nominal = CL_mu.NominalValue;
            NS = isstable(CL_Nominal);
            if (NS == 1)
                app.NominaleStabilitEditField_12.Value = 'Sì';
            else
                app.NominaleStabilitEditField_12.Value = 'No';
            end
            
            %           Aggiorno campo 'ordine del controllore'
            [m,n]=size(K_mu.A);
            app.OrdinedelcontrolloreEditField_12.Value = m;
            
            app.SintesiButton.Text = 'Sintesi µ';
            app.SintesiButton.Enable = 'on';
        end

        % Callback function
        function RobustAnalysisButton_3Pushed(app, event)
            global K_mu
            
            sys_ic = evalin('base','sys_ic');
            
            app.RobustAnalysisButton_3.Enable = 'off';
            app.RobustAnalysisButton_3.Text = 'Sto eseguendo...';
            
            K = K_mu;
            mu_hdd;
            
            app.RobustAnalysisButton_3.Text = 'Robust Analysis';
            app.RobustAnalysisButton_3.Enable = 'on';
            
        end

        % Callback function
        function FrequencyResponseButton_3Pushed(app, event)
            global K_mu;
            
            app.FrequencyResponseButton_3.Enable = 'off';
            app.FrequencyResponseButton_3.Text = 'Sto eseguendo...';
            
            K = K_mu;
            frs_hdd;
            
            app.FrequencyResponseButton_3.Text = 'Frequency Response';
            app.FrequencyResponseButton_3.Enable = 'on';
            
        end

        % Callback function
        function TimeResponseButton_3Pushed(app, event)
            global K_mu;
            
            app.TimeResponseButton_3.Enable = 'off';
            app.TimeResponseButton_3.Text = 'Sto eseguendo...';
            
            K = K_mu;
            mcs_hdd;
            
            app.TimeResponseButton_3.Text = 'Time Response';
            app.TimeResponseButton_3.Enable = 'on';
            
        end

        % Callback function
        function SintesiLSDPHButtonPushed(app, event)
            global K; global K_lsh;
            
            app.LSDP_SintesiHButton.Enable = 'off';
            app.LSDP_SintesiHButton.Text = 'Sto sintetizzando...';
            
            lsh_hdd;
            
            %           Aggiorno campo 'Nominale Stabilità
            CL_Nominal = cl;
            NS = isstable(CL_Nominal);
            if (NS == 1)
                app.LSDP_NominaleStabilitEditField.Value = 'Sì';
            else
                app.LSDP_NominaleStabilitEditField.Value = 'No';
            end
            
            %           Aggiorno campo 'ordine del controllore'
            [m,n]=size(K_lsh.A);
            app.LSDP_OrdinedelcontrolloreEditField.Value = m;
            
            app.LSDP_SintesiHButton.Text = 'Sintesi LSDP H∞';
            app.LSDP_SintesiHButton.Enable = 'on';
        end

        % Callback function
        function RobustAnalysisButton_4Pushed(app, event)
            global K_lsh;
            
            sys_ic = evalin('base','sys_ic');
            
            app.LSDP_RobustAnalysisButton.Enable = 'off';
            app.LSDP_RobustAnalysisButton.Text = 'Sto eseguendo...';
            
            K = K_lsh;
            mu_hdd;
            
            app.LSDP_RobustAnalysisButton.Text = 'Robust Analysis';
            app.LSDP_RobustAnalysisButton.Enable = 'on';
            
        end

        % Callback function
        function FrequencyResponseButton_4Pushed(app, event)
            global K_lsh;
            
            app.LSDP_FrequencyResponseButton.Enable = 'off';
            app.LSDP_FrequencyResponseButton.Text = 'Sto eseguendo...';
            
            K = K_lsh;
            frs_hdd;
            
            app.LSDP_FrequencyResponseButton.Text = 'Frequency Response';
            app.LSDP_FrequencyResponseButton.Enable = 'on';
            
        end

        % Callback function
        function TimeResponseButton_4Pushed(app, event)
            global K_lsh;
            
            app.LSDP_TimeResponseButton.Enable = 'off';
            app.LSDP_TimeResponseButton.Text = 'Sto eseguendo...';
            
            K = K_lsh;
            mcs_hdd;
            
            app.LSDP_TimeResponseButton.Text = 'Time Response';
            app.LSDP_TimeResponseButton.Enable = 'on';
            
        end

        % Callback function
        function SintesiPIDButtonPushed(app, event)
            global K; global K_PID;
            
            app.PID_SintesiButton.Enable = 'off';
            app.PID_SintesiButton.Text = 'Sto sintetizzando...';
            
            close all; clc;
            olp_hdd;
            [K,Tcl]=TraditionalPID(G, app.WcEditField.Value, app.OSEditField.Value, ...
                app.AMPEditField.Value, app.STEditField.Value);
            K_PID = K;
            
            %           Aggiorno campo 'Nominale Stabilità
            CL_Nominal = Tcl;
            NS = isstable(CL_Nominal);
            if (NS == 1)
                app.PID_NominaleStabilitEditField_.Value = 'Sì';
            else
                app.PID_NominaleStabilitEditField_.Value = 'No';
            end
            
            %           Aggiorno campo 'ordine del controllore'
            tmp=ss(K_PID);
            [m,n]=size(tmp.A);
            app.PID_OrdinedelcontrolloreEditField.Value = m;
            
            app.PID_SintesiButton.Text = 'Sintesi PID';
            app.PID_SintesiButton.Enable = 'on';
        end

        % Callback function
        function RobustAnalysisButton_5Pushed(app, event)
            global K_PID;
            
            sys_ic = evalin('base','sys_ic');
            
            app.PID_RobustAnalysisButton.Enable = 'off';
            app.PID_RobustAnalysisButton.Text = 'Sto eseguendo...';
            
            K = K_PID;
            mu_hdd;
            
            app.PID_RobustAnalysisButton.Text = 'Robust Analysis';
            app.PID_RobustAnalysisButton.Enable = 'on';
            
        end

        % Callback function
        function FrequencyResponseButton_5Pushed(app, event)
            global K_PID;
            
            app.PID_FrequencyResponseButton.Enable = 'off';
            app.PID_FrequencyResponseButton.Text = 'Sto eseguendo...';
            
            K = K_PID;
            frs_hdd;
            
            app.PID_FrequencyResponseButton.Text = 'Frequency Response';
            app.PID_FrequencyResponseButton.Enable = 'on';
            
        end

        % Callback function
        function TimeResponseButton_5Pushed(app, event)
            global K_PID;
            
            app.PID_TimeResponseButton.Enable = 'off';
            app.PID_TimeResponseButton.Text = 'Sto eseguendo...';
            
            K = K_PID;
            mcs_hdd;
            
            app.PID_TimeResponseButton.Text = 'Time Response';
            app.PID_TimeResponseButton.Enable = 'on';
             
        end

        % Callback function
        function SintesiLQGLTR_uButtonPushed(app, event)
            global K; global K_lqgltr_u;
            
            app.SintesiLQGLTR_uButton.Enable = 'off';
            app.SintesiLQGLTR_uButton.Text = 'Sto sintetizzando...';
            
            q = app.qEditField_2.Value;
            
            % Possibilità di vedere diversi valori di recupero
            if ( q == -Inf )
                q = [1e3 1e6 1e9 1e12];
                ltru_hdd;
                assignin('base','K', []); assignin('base','K_lqgltr_u', []); 
                app.NominaleStabilitEditField_15.Value = '';
                app.OrdinedelcontrolloreEditField_15.Value = 0;
            else
            
                ltru_hdd;
            
            %           Aggiorno campo 'Nominale Stabilità  
                CL_Nominal = loopsens(G(2),K_lqgltr_u);
                NS = isstable(CL_Nominal);
                %NS = isstable(loopsens(G,K_lqgltr_u));
                if (NS == 1)
                    app.NominaleStabilitEditField_15.Value = 'Sì';
                else
                    app.NominaleStabilitEditField_15.Value = 'No';
                end
            
            %           Aggiorno campo 'ordine del controllore'
                tmp = ss(K_lqgltr_u);
                [m,n]=size(tmp.A);
                app.OrdinedelcontrolloreEditField_15.Value = m;
            end
            
            app.SintesiLQGLTR_uButton.Text = 'Sintesi LQG/LTR_u';
            app.SintesiLQGLTR_uButton.Enable = 'on';            
        end

        % Callback function
        function RobustAnalysisButton_6Pushed(app, event)
            global K_lqgltr_u;
            
            sys_ic = evalin('base','sys_ic');
            
            app.RobustAnalysisButton_6.Enable = 'off';
            app.RobustAnalysisButton_6.Text = 'Sto eseguendo...';
            
            K = K_lqgltr_u;
            mu_hdd;
            
            app.RobustAnalysisButton_6.Text = 'Robust Analysis';
            app.RobustAnalysisButton_6.Enable = 'on';
            
        end

        % Callback function
        function FrequencyResponseButton_6Pushed(app, event)
            global K_lqgltr_u;
            
            app.FrequencyResponseButton_6.Enable = 'off';
            app.FrequencyResponseButton_6.Text = 'Sto eseguendo...';
            
            K = K_lqgltr_u;
            frs_hdd;
            
            app.FrequencyResponseButton_6.Text = 'Frequency Response';
            app.FrequencyResponseButton_6.Enable = 'on';
             
        end

        % Callback function
        function TimeResponseButton_6Pushed(app, event)
            global K_lqgltr_u;
            
            app.TimeResponseButton_6.Enable = 'off';
            app.TimeResponseButton_6.Text = 'Sto eseguendo...';
            
            K = K_lqgltr_u;
            mcs_hdd;
            
            app.TimeResponseButton_6.Text = 'Time Response';
            app.TimeResponseButton_6.Enable = 'on';            
        end

        % Callback function
        function SintesiLQGLTR_yButtonPushed(app, event)
            global K; global K_lqgltr_y;
            
            app.SintesiLQGLTR_yButton.Enable = 'off';
            app.SintesiLQGLTR_yButton.Text = 'Sto sintetizzando...';
            
            q = app.qEditField_3.Value;
            
            % Possibilità di vedere diversi valori di recupero
            if ( q == -Inf )
                q = [1e3 1e6 1e9 1e12];
                ltry_hdd;
                assignin('base','K', []); assignin('base','K_lqgltr_y', []); 
                app.NominaleStabilitEditField_16.Value = '';
                app.OrdinedelcontrolloreEditField_16.Value = 0;
            else
            
                ltry_hdd;
            
            %           Aggiorno campo 'Nominale Stabilità
                CL_Nominal = loopsens(G(2),K_lqgltr_y);
                NS = isstable(CL_Nominal);
                %NS = isstable(loopsens(G,K_lqgltr_y));
                if (NS == 1)
                    app.NominaleStabilitEditField_16.Value = 'Sì';
                else
                    app.NominaleStabilitEditField_16.Value = 'No';
                end
            
            %           Aggiorno campo 'ordine del controllore'
                tmp = ss(K_lqgltr_y);
                [m,n]=size(tmp.A);
                app.OrdinedelcontrolloreEditField_16.Value = m;
            end
            
            app.SintesiLQGLTR_yButton.Text = 'Sintesi LQG/LTR_y';
            app.SintesiLQGLTR_yButton.Enable = 'on';                
        end

        % Callback function
        function RobustAnalysisButton_7Pushed(app, event)
            global K_lqgltr_y;
            
            sys_ic = evalin('base','sys_ic');
            
            app.RobustAnalysisButton_7.Enable = 'off';
            app.RobustAnalysisButton_7.Text = 'Sto eseguendo...';
            
            K = K_lqgltr_y;
            mu_hdd;
            
            app.RobustAnalysisButton_7.Text = 'Robust Analysis';
            app.RobustAnalysisButton_7.Enable = 'on';
            
        end

        % Callback function
        function FrequencyResponseButton_7Pushed(app, event)
            global K_lqgltr_y;
            
            app.FrequencyResponseButton_7.Enable = 'off';
            app.FrequencyResponseButton_7.Text = 'Sto eseguendo...';
            
            K = K_lqgltr_y;
            frs_hdd;
            
            app.FrequencyResponseButton_7.Text = 'Frequency Response';
            app.FrequencyResponseButton_7.Enable = 'on';
            
        end

        % Callback function
        function TimeResponseButton_7Pushed(app, event)
            global K_lqgltr_y;
            
            app.TimeResponseButton_7.Enable = 'off';
            app.TimeResponseButton_7.Text = 'Sto eseguendo...';
            
            K = K_lqgltr_y;
            mcs_hdd;
            
            app.TimeResponseButton_7.Text = 'Time Response';
            app.TimeResponseButton_7.Enable = 'on';              
        end

        % Button pushed function: Hinf_VerificaSpecificheButton
        function Hinf_VerificaSpecificheButtonPushed(app, event)
            %K = K_hin;
            verifiche('K_hin');
        end

        % Callback function
        function VerificheButton_2Pushed(app, event)
            verifiche;
        end

        % Callback function
        function VerificheButton_3Pushed(app, event)
            verifiche;
        end

        % Callback function
        function VerificheButton_4Pushed(app, event)
            verifiche;
        end

        % Callback function
        function VerificheButton_5Pushed(app, event)
            verifiche;
        end

        % Callback function
        function VerificheButton_6Pushed(app, event)
            verifiche;
        end

        % Button pushed function: Hinf_FunzioniPesoButton
        function Hinf_FunzioniPesoButtonPushed(app, event)
            frequency_response_of_weight;
        end

        % Button pushed function: LSDP_SintesiButton
        function LSDP_SintesiButtonPushed(app, event)
            global K_lsh;
            
            K = K_lsh;
            
            app.LSDP_SintesiButton.Enable = 'off';
            app.LSDP_SintesiButton.Text = 'Sto sintetizzando...';
            
            lsh_hdd;
            
            %           Aggiorno campo 'Nominale Stabilità
            CL_Nominal = cl;
            NS = isstable(CL_Nominal);
            
            if (NS == 1)
                app.LSDP_NSLamp.Color = 'green';
            else
                app.LSDP_NSLamp.Color = 'red';
            end
            
            %           Aggiorno campo 'ordine del controllore'
            [m,n]=size(K.A);
            app.LSDP_OrdinedelcontrolloreEditField.Value = m;
            
            app.LSDP_SintesiButton.Text = 'Sintesi LSDP H∞';
            app.LSDP_SintesiButton.Enable = 'on';
        end

        % Button pushed function: LSDP_RobustAnalysisButton
        function LSDP_RobustAnalysisButtonPushed(app, event)
            global K_lsh
            
            sys_ic = evalin('base','sys_ic');
            
            app.LSDP_RobustAnalysisButton.Enable = 'off';
            app.LSDP_RobustAnalysisButton.Text = 'Sto eseguendo...';
            
            K = K_lsh;
            [stabmarg, perfmarg] = mu_hdd(sys_ic, K, 'K_lsh');
            clp_ic = lft(sys_ic,K,1,1);
            %w = logspace(3,5,100);
            w = logspace(2,5,100);
            sv = sigma(clp_ic.Nominal,w);
            
            RS = stabmarg.LowerBound;
            app.LSDP_RSLabel.Text = [num2str(RS*100), ' %'];
            if (RS > 1)
                app.LSDP_RSLamp.Color = 'green';
            else
                app.LSDP_RSLamp.Color = 'red';
            end
            
            NP = max(sv(1,:));
            app.LSDP_NPLabel.Text = [num2str(100/NP), ' %'];
            if (NP < 1)
                app.LSDP_NPLamp.Color = 'green';
            else
                app.LSDP_NPLamp.Color = 'red';
            end
            
            RP = perfmarg.LowerBound;
            app.LSDP_RPLabel.Text = [num2str(RP*100), ' %'];
            if (RP > 1)
                app.LSDP_RPLamp.Color = 'green';
            else
                app.LSDP_RPLamp.Color = 'red';
            end
            
            app.LSDP_RobustAnalysisButton.Text = 'Robust Analysis';
            app.LSDP_RobustAnalysisButton.Enable = 'on';
        end

        % Button pushed function: mu_RobustAnalysisButton
        function mu_RobustAnalysisButtonPushed(app, event)
            global K_mu
            
            sys_ic = evalin('base','sys_ic');
            
            app.mu_RobustAnalysisButton.Enable = 'off';
            app.mu_RobustAnalysisButton.Text = 'Sto eseguendo...';
            
            K = K_mu;
            [stabmarg, perfmarg] = mu_hdd(sys_ic, K, 'K_mu');
            %sv = evalin('base','sv');
            clp_ic = lft(sys_ic,K,1,1);
            %w = logspace(3,5,100);
            w = logspace(2,5,100);
            sv = sigma(clp_ic.Nominal,w);
            
            RS = stabmarg.LowerBound;
            app.mu_RSLabel.Text = [num2str(RS*100), ' %'];
            if (RS > 1)
                app.mu_RSLamp.Color = 'green';
            else
                app.mu_RSLamp.Color = 'red';
            end
            
            NP = max(sv(1,:));
            app.mu_NPLabel.Text = [num2str(100/NP), ' %'];
            if (NP < 1)
                app.mu_NPLamp.Color = 'green';
            else
                app.mu_NPLamp.Color = 'red';
            end
            
            RP = perfmarg.LowerBound;
            app.mu_RPLabel.Text = [num2str(RP*100), ' %'];
            if (RP > 1)
                app.mu_RPLamp.Color = 'green';
            else
                app.mu_RPLamp.Color = 'red';
            end
            
            app.mu_RobustAnalysisButton.Text = 'Robust Analysis';
            app.mu_RobustAnalysisButton.Enable = 'on';
        end

        % Button pushed function: PID_RobustAnalysisButton
        function PID_RobustAnalysisButtonPushed(app, event)
            global K_PID
            
            sys_ic = evalin('base','sys_ic');
            
            app.PID_RobustAnalysisButton.Enable = 'off';
            app.PID_RobustAnalysisButton.Text = 'Sto eseguendo...';
            
            K = K_PID;
            [stabmarg, perfmarg] = mu_hdd(sys_ic, K, 'K_PID');
            %sv = evalin('base','sv');
            clp_ic = lft(sys_ic,K,1,1);
            %w = logspace(3,5,100);
            w = logspace(2,5,100);
            sv = sigma(clp_ic.Nominal,w);
            
            RS = stabmarg.LowerBound;
            app.PID_RSLabel.Text = [num2str(RS*100), ' %'];
            if (RS > 1)
                app.PID_RSLamp.Color = 'green';
            else
                app.PID_RSLamp.Color = 'red';
            end
            
            NP = max(sv(1,:));
            app.PID_NPLabel.Text = [num2str(100/NP), ' %'];
            if (NP < 1)
                app.PID_NPLamp.Color = 'green';
            else
                app.PID_NPLamp.Color = 'red';
            end
            
            RP = perfmarg.LowerBound;
            app.PID_RPLabel.Text = [num2str(RP*100), ' %'];
            if (RP > 1)
                app.PID_RPLamp.Color = 'green';
            else
                app.PID_RPLamp.Color = 'red';
            end
            
            app.PID_RobustAnalysisButton.Text = 'Robust Analysis';
            app.PID_RobustAnalysisButton.Enable = 'on';
        end

        % Button pushed function: mu_SintesiButton
        function mu_SintesiButtonPushed(app, event)
            global K_mu;
            
            K = K_mu;
            
            sys_ic = evalin('base','sys_ic');
            
            app.mu_SintesiButton.Enable = 'off';
            app.mu_SintesiButton.Text = 'Sto sintetizzando...';
            
            ms_hdd;
            
            %           Aggiorno campo 'Nominale Stabilità
            CL_Nominal = CL_mu.NominalValue;
            NS = isstable(CL_Nominal);
            
            if (NS == 1)
                app.mu_NSLamp.Color = 'green';
            else
                app.mu_NSLamp.Color = 'red';
            end
            
            %           Aggiorno campo 'ordine del controllore'
            [m,n]=size(K.A);
            app.mu_OrdinedelcontrolloreEditField.Value = m;
            
            app.mu_SintesiButton.Text = 'Sintesi µ';
            app.mu_SintesiButton.Enable = 'on';
        end

        % Button pushed function: PID_SintesiButton
        function PID_SintesiButtonPushed(app, event)
            global K_PID;
            
            CL_Nominal = evalin('base','CL_Nominal');
            
            %K = K_PID;
            
            app.PID_SintesiButton.Enable = 'off';
            app.PID_SintesiButton.Text = 'Sto sintetizzando...';
            
            close all; clc;
            olp_hdd;
            %[K,Tcl]=TraditionalPID(G, app.WcEditField.Value, app.OSEditField.Value, ...
            %    app.AMPEditField.Value, app.STEditField.Value);
            TraditionalPID;
            K_PID = K;
            
            %           Aggiorno campo 'Nominale Stabilità
            %CL_Nominal = Tcl;
            NS = isstable(CL_Nominal);
            
            if (NS == 1)
                app.PID_NSLamp.Color = 'green';
            else
                app.PID_NSLamp.Color = 'red';
            end
            
            %           Aggiorno campo 'ordine del controllore'
            tmp=ss(K_PID);
            [m,n]=size(tmp.A);
            app.PID_OrdinedelcontrolloreEditField.Value = m;
            
            app.PID_SintesiButton.Text = 'Sintesi PID';
            app.PID_SintesiButton.Enable = 'on';
        end

        % Callback function
        function LSDP_FunzioniPesoButtonPushed(app, event)
            frequency_response_of_weight;
        end

        % Button pushed function: mu_FunzioniPesoButton
        function mu_FunzioniPesoButtonPushed(app, event)
            frequency_response_of_weight;
        end

        % Callback function
        function PID_FunzioniPesoButtonPushed(app, event)
            frequency_response_of_weight;
        end

        % Button pushed function: LSDP_RispostainfrequenzaButton
        function LSDP_RispostainfrequenzaButtonPushed(app, event)
            global K_lsh;
            
            app.LSDP_RispostainfrequenzaButton.Enable = 'off';
            app.LSDP_RispostainfrequenzaButton.Text = 'Sto eseguendo...';
            
            K = K_lsh;
            frs_hdd;
            
            app.LSDP_RispostainfrequenzaButton.Text = 'Frequency Response';
            app.LSDP_RispostainfrequenzaButton.Enable = 'on';
            
        end

        % Button pushed function: mu_RispostainfrequenzaButton
        function mu_RispostainfrequenzaButtonPushed(app, event)
            global K_mu;
            
            app.mu_RispostainfrequenzaButton.Enable = 'off';
            app.mu_RispostainfrequenzaButton.Text = 'Sto eseguendo...';
            
            K = K_mu;
            frs_hdd;
            
            app.mu_RispostainfrequenzaButton.Text = 'Frequency Response';
            app.mu_RispostainfrequenzaButton.Enable = 'on';
            
        end

        % Button pushed function: PID_RispostainfrequenzaButton
        function PID_RispostainfrequenzaButtonPushed(app, event)
            global K_PID;
            
            app.PID_RispostainfrequenzaButton.Enable = 'off';
            app.PID_RispostainfrequenzaButton.Text = 'Sto eseguendo...';
            
            K = K_PID;
            frs_hdd;
            
            app.PID_RispostainfrequenzaButton.Text = 'Frequency Response';
            app.PID_RispostainfrequenzaButton.Enable = 'on';
            
        end

        % Button pushed function: LSDP_RispostaTemporaleButton
        function LSDP_RispostaTemporaleButtonPushed(app, event)
            global K_lsh;
            
            app.LSDP_RispostaTemporaleButton.Enable = 'off';
            app.LSDP_RispostaTemporaleButton.Text = 'Sto eseguendo...';
            
            K = K_lsh;
            mcs_hdd;
            
            app.LSDP_RispostaTemporaleButton.Text = 'Time Response';
            app.LSDP_RispostaTemporaleButton.Enable = 'on';
        end

        % Button pushed function: mu_RispostaTemporaleButton
        function mu_RispostaTemporaleButtonPushed(app, event)
            global K_mu;
            
            app.mu_RispostaTemporaleButton.Enable = 'off';
            app.mu_RispostaTemporaleButton.Text = 'Sto eseguendo...';
            
            K = K_mu;
            mcs_hdd;
            
            app.mu_RispostaTemporaleButton.Text = 'Time Response';
            app.mu_RispostaTemporaleButton.Enable = 'on';
        end

        % Button pushed function: PID_RispostaTemporaleButton
        function PID_RispostaTemporaleButtonPushed(app, event)
            global K_PID;
            
            app.PID_RispostaTemporaleButton.Enable = 'off';
            app.PID_RispostaTemporaleButton.Text = 'Sto eseguendo...';
            
            K = K_PID;
            mcs_hdd;
            
            app.PID_RispostaTemporaleButton.Text = 'Time Response';
            app.PID_RispostaTemporaleButton.Enable = 'on';
        end

        % Button pushed function: LSDP_VerificaSpecificheButton
        function LSDP_VerificaSpecificheButtonPushed(app, event)
            verifiche('K_lsh');
        end

        % Button pushed function: mu_VerificaSpecificheButton
        function mu_VerificaSpecificheButtonPushed(app, event)
            verifiche('K_mu');
        end

        % Button pushed function: PID_VerificaSpecificheButton
        function PID_VerificaSpecificheButtonPushed(app, event)
            verifiche('K_PID');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.IntroTab.Units = 'pixels';
            app.HardDiskDriveProjectLabel = uilabel(app.IntroTab);
            app.HardDiskDriveProjectLabel.Text = 'Hard Disk Drive Project';
            app.TextArea = uitextarea(app.IntroTab);
            app.TextArea.Value = {'La GUI è stata sviluppata come supporto all''analisi ed alla presentazione dei risultati raggiunti lungo lo '; 'svolgimento del progetto. In particolare, dato il modello del disco rigido, sono stati sintetizzati i seguenti controllori:'; ''; '          - controllore H∞;'; '          - controllore LSDP H∞;'; '          - controllore µ;'; '          - controllore tradizionale;'; ''; ''; 'Le specifiche sono le seguenti:'; ''; '         • Peak closed-loop gain < 4 dB'; '         • Open-loop gain > 20 dB at 100 Hz '; '         • Steady-state error < 0.1 μm'; '         • Settling time < 1.5 ms'; '         • Closed-loop bandwidth > 1000 Hz'; '         • Gain margin > 5 dB'; '         • Phase margin > 40 deg'; '         • u(t) < 1.2 V'; '         • Disturbance rejection'; '         • Noise rejection'};
            app.HTab = uitab(app.TabGroup);
            app.HTab.Title = 'H∞';
            app.HTab.Units = 'pixels';
            app.TextArea_3Label = uilabel(app.HTab);
            app.TextArea_3Label.Text = '';
            app.TextArea_3 = uitextarea(app.HTab);
            app.TextArea_3.Value = {'Il controllore H_∞ minimizza la norma infinito di Tzw, funzione di trasferimento che lega gli ingressi all''output esogeno. La sintesi è effettuata mediante la funzione MATLAB hinfsyn, previa costruzione dell''impianto '; 'generalizzato, definizione delle funzioni peso e descrizione delle incertezze dell''impianto.'; 'La finestra permette la sintesi del controllore, l''analisi della risposta in frequenza e temporale del sistema '; 'in ciclo chiuso perturbato.'};
            app.Hinf_SintesiButton = uibutton(app.HTab, 'push');
            app.Hinf_SintesiButton.ButtonPushedFcn = createCallbackFcn(app, @Hinf_SintesiButtonPushed, true);
            app.Hinf_SintesiButton.Text = 'Sintesi H∞';
            app.Hinf_AnalisidiRobustaStabilitLabel = uilabel(app.HTab);
            app.Hinf_AnalisidiRobustaStabilitLabel.Text = 'Analisi di Robusta Stabilità, Prestazione';
            app.Hinf_RobustAnalysisButton = uibutton(app.HTab, 'push');
            app.Hinf_RobustAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Hinf_RobustAnalysisButtonPushed, true);
            app.Hinf_RobustAnalysisButton.Text = 'Robust Analysis';
            app.Hinf_AnalisidellarispostainfrequenzaimpiantononscalatoLabel = uilabel(app.HTab);
            app.Hinf_AnalisidellarispostainfrequenzaimpiantononscalatoLabel.Text = 'Analisi della risposta in frequenza (impianto perturbato non scalato)';
            app.Hinf_RispostainfrequenzaButton = uibutton(app.HTab, 'push');
            app.Hinf_RispostainfrequenzaButton.ButtonPushedFcn = createCallbackFcn(app, @Hinf_RispostainfrequenzaButtonPushed, true);
            app.Hinf_RispostainfrequenzaButton.Text = 'Risposta in frequenza';
            app.Hinf_AnalisidellarispostatemporaleimpiantoLabel = uilabel(app.HTab);
            app.Hinf_AnalisidellarispostatemporaleimpiantoLabel.Text = 'Analisi della risposta temporale (impianto perturbato scalato)';
            app.Hinf_RispostaTemporaleButton = uibutton(app.HTab, 'push');
            app.Hinf_RispostaTemporaleButton.ButtonPushedFcn = createCallbackFcn(app, @Hinf_RispostaTemporaleButtonPushed, true);
            app.Hinf_RispostaTemporaleButton.Text = 'Risposta Temporale';
            app.OrdinedelcontrolloreEditField_10Label = uilabel(app.HTab);
            app.OrdinedelcontrolloreEditField_10Label.Text = 'Ordine del controllore';
            app.Hinf_OrdinedelcontrolloreEditField = uieditfield(app.HTab, 'numeric');
            app.Hinf_VerificaSpecificheButton = uibutton(app.HTab, 'push');
            app.Hinf_VerificaSpecificheButton.ButtonPushedFcn = createCallbackFcn(app, @Hinf_VerificaSpecificheButtonPushed, true);
            app.Hinf_VerificaSpecificheButton.Text = 'Verifica specifiche';
            app.NominaleStabilitLampLabel = uilabel(app.HTab);
            app.NominaleStabilitLampLabel.Text = 'Nominale Stabilità';
            app.Hinf_NSLamp = uilamp(app.HTab);
            app.RobustaStabilitLabel = uilabel(app.HTab);
            app.RobustaStabilitLabel.Text = 'Robusta Stabilità';
            app.Hinf_RSLamp = uilamp(app.HTab);
            app.NominalePrestazioneLabel = uilabel(app.HTab);
            app.NominalePrestazioneLabel.Text = 'Nominale Prestazione';
            app.Hinf_NPLamp = uilamp(app.HTab);
            app.RobustaPrestazioneLabel = uilabel(app.HTab);
            app.RobustaPrestazioneLabel.Text = 'Robusta Prestazione';
            app.Hinf_RPLamp = uilamp(app.HTab);
            app.Hinf_RSLabel = uilabel(app.HTab);
            app.Hinf_RSLabel.Text = '';
            app.Hinf_NPLabel = uilabel(app.HTab);
            app.Hinf_NPLabel.Text = '';
            app.Hinf_RPLabel = uilabel(app.HTab);
            app.Hinf_RPLabel.Text = '';
            app.Hinf_VerificadelleSpecificheLabel = uilabel(app.HTab);
            app.Hinf_VerificadelleSpecificheLabel.Text = 'Verifica delle specifiche';
            app.Hinf_AnalisidiRobustaStabilitLabel2 = uilabel(app.HTab);
            app.Hinf_AnalisidiRobustaStabilitLabel2.Text = 'Nominale e Robusta Prestazione';
            app.Hinf_FunzioniPesoLabel = uilabel(app.HTab);
            app.Hinf_FunzioniPesoLabel.Text = 'Visualizza la risposta in frequenza delle funzioni peso';
            app.Hinf_FunzioniPesoButton = uibutton(app.HTab, 'push');
            app.Hinf_FunzioniPesoButton.ButtonPushedFcn = createCallbackFcn(app, @Hinf_FunzioniPesoButtonPushed, true);
            app.Hinf_FunzioniPesoButton.Text = 'Funzioni peso';
            app.LSDPHTab = uitab(app.TabGroup);
            app.LSDPHTab.Title = 'LSDP H∞';
            app.LSDPHTab.Units = 'pixels';
            app.TextArea_5Label = uilabel(app.LSDPHTab);
            app.TextArea_5Label.Text = '';
            app.TextArea_5 = uitextarea(app.LSDPHTab);
            app.TextArea_5.Value = {'Questa sezione permette la sintesi di un controllore H∞ mediante la loop-shaping design procedure proposta da Glover-McFarlane. La sintesi viene effettuata applicando un prefiltro W1 al modello G in modo da '; 'ottenere Gs, modello avente risposta in frequenza desiderata. Si sintetizza quindi il controllore Ks mediante il   comando MATLAB ncfsyn, ottenendo infine il controllore finale K = W1*Ks. La finestra permette infine l''analisi della risposta in frequenza e temporale del sistema in ciclo chiuso perturbato.'};
            app.LSDP_SintesiButton = uibutton(app.LSDPHTab, 'push');
            app.LSDP_SintesiButton.ButtonPushedFcn = createCallbackFcn(app, @LSDP_SintesiButtonPushed, true);
            app.LSDP_SintesiButton.Text = 'Sintesi LSDP H∞';
            app.LSDP_AnalisidiRobustaStabilitLabel = uilabel(app.LSDPHTab);
            app.LSDP_AnalisidiRobustaStabilitLabel.Text = 'Analisi di Robusta Stabilità, Prestazione';
            app.LSDP_RobustAnalysisButton = uibutton(app.LSDPHTab, 'push');
            app.LSDP_RobustAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LSDP_RobustAnalysisButtonPushed, true);
            app.LSDP_RobustAnalysisButton.Text = 'Robust Analysis';
            app.LSDP_AnalisidellarispostainfrequenzaimpiantononscalatoLabel = uilabel(app.LSDPHTab);
            app.LSDP_AnalisidellarispostainfrequenzaimpiantononscalatoLabel.Text = 'Analisi della risposta in frequenza (impianto perturbato non scalato)';
            app.LSDP_RispostainfrequenzaButton = uibutton(app.LSDPHTab, 'push');
            app.LSDP_RispostainfrequenzaButton.ButtonPushedFcn = createCallbackFcn(app, @LSDP_RispostainfrequenzaButtonPushed, true);
            app.LSDP_RispostainfrequenzaButton.Text = 'Risposta in frequenza';
            app.LSDP_AnalisidellarispostatemporaleimpiantoLabel = uilabel(app.LSDPHTab);
            app.LSDP_AnalisidellarispostatemporaleimpiantoLabel.Text = 'Analisi della risposta temporale (impianto perturbato scalato)';
            app.LSDP_RispostaTemporaleButton = uibutton(app.LSDPHTab, 'push');
            app.LSDP_RispostaTemporaleButton.ButtonPushedFcn = createCallbackFcn(app, @LSDP_RispostaTemporaleButtonPushed, true);
            app.LSDP_RispostaTemporaleButton.Text = 'Risposta Temporale';
            app.LSDP_VerificaSpecificheButton = uibutton(app.LSDPHTab, 'push');
            app.LSDP_VerificaSpecificheButton.ButtonPushedFcn = createCallbackFcn(app, @LSDP_VerificaSpecificheButtonPushed, true);
            app.LSDP_VerificaSpecificheButton.Text = 'Verifica specifiche';
            app.LSDP_RSLabel = uilabel(app.LSDPHTab);
            app.LSDP_RSLabel.Text = '';
            app.LSDP_NPLabel = uilabel(app.LSDPHTab);
            app.LSDP_NPLabel.Text = '';
            app.LSDP_RPLabel = uilabel(app.LSDPHTab);
            app.LSDP_RPLabel.Text = '';
            app.LSDP_VerificadelleSpecificheLabel = uilabel(app.LSDPHTab);
            app.LSDP_VerificadelleSpecificheLabel.Text = 'Verifica delle specifiche';
            app.LSDP_AnalisidiRobustaStabilitLabel2 = uilabel(app.LSDPHTab);
            app.LSDP_AnalisidiRobustaStabilitLabel2.Text = 'Nominale e Robusta Prestazione';
            app.OrdinedelcontrolloreEditField_17Label = uilabel(app.LSDPHTab);
            app.OrdinedelcontrolloreEditField_17Label.Text = 'Ordine del controllore';
            app.LSDP_OrdinedelcontrolloreEditField = uieditfield(app.LSDPHTab, 'numeric');
            app.Hinf_NSLamp_2Label = uilabel(app.LSDPHTab);
            app.Hinf_NSLamp_2Label.Text = 'Nominale Stabilità';
            app.LSDP_NSLamp = uilamp(app.LSDPHTab);
            app.Hinf_RSLamp_2Label = uilabel(app.LSDPHTab);
            app.Hinf_RSLamp_2Label.Text = 'Robusta Stabilità';
            app.LSDP_RSLamp = uilamp(app.LSDPHTab);
            app.Hinf_NPLamp_2Label = uilabel(app.LSDPHTab);
            app.Hinf_NPLamp_2Label.Text = 'Nominale Prestazione';
            app.LSDP_NPLamp = uilamp(app.LSDPHTab);
            app.Hinf_RPLamp_2Label = uilabel(app.LSDPHTab);
            app.Hinf_RPLamp_2Label.Text = 'Robusta Prestazione';
            app.LSDP_RPLamp = uilamp(app.LSDPHTab);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'µ';
            app.Tab.Units = 'pixels';
            app.TextArea_4Label = uilabel(app.Tab);
            app.TextArea_4Label.Text = '';
            app.TextArea_4 = uitextarea(app.Tab);
            app.TextArea_4.Value = {'Il controllore µ minimizza il valore singolare strutturato della matrice di trasferimento N rispetto al set di '; 'incertezza aumentato Δp. La sintesi è effettuata mediante la funzione MATLAB mcsyn, previa costruzione '; 'dell''impianto generalizzato, definizione delle funzioni peso e descrizione delle incertezze dell''impianto.'; 'La finestra permette la sintesi del controllore, l''analisi della risposta in frequenza e temporale del sistema in '; 'ciclo chiuso.'};
            app.mu_SintesiButton = uibutton(app.Tab, 'push');
            app.mu_SintesiButton.ButtonPushedFcn = createCallbackFcn(app, @mu_SintesiButtonPushed, true);
            app.mu_SintesiButton.Text = 'Sintesi µ';
            app.mu_AnalisidiRobustaStabilitLabel = uilabel(app.Tab);
            app.mu_AnalisidiRobustaStabilitLabel.Text = 'Analisi di Robusta Stabilità, Prestazione';
            app.mu_RobustAnalysisButton = uibutton(app.Tab, 'push');
            app.mu_RobustAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @mu_RobustAnalysisButtonPushed, true);
            app.mu_RobustAnalysisButton.Text = 'Robust Analysis';
            app.mu_AnalisidellarispostainfrequenzaimpiantononscalatoLabel = uilabel(app.Tab);
            app.mu_AnalisidellarispostainfrequenzaimpiantononscalatoLabel.Text = 'Analisi della risposta in frequenza (impianto perturbato non scalato)';
            app.mu_RispostainfrequenzaButton = uibutton(app.Tab, 'push');
            app.mu_RispostainfrequenzaButton.ButtonPushedFcn = createCallbackFcn(app, @mu_RispostainfrequenzaButtonPushed, true);
            app.mu_RispostainfrequenzaButton.Text = 'Risposta in frequenza';
            app.mu_AnalisidellarispostatemporaleimpiantoLabel = uilabel(app.Tab);
            app.mu_AnalisidellarispostatemporaleimpiantoLabel.Text = 'Analisi della risposta temporale (impianto perturbato scalato)';
            app.mu_RispostaTemporaleButton = uibutton(app.Tab, 'push');
            app.mu_RispostaTemporaleButton.ButtonPushedFcn = createCallbackFcn(app, @mu_RispostaTemporaleButtonPushed, true);
            app.mu_RispostaTemporaleButton.Text = 'Risposta Temporale';
            app.mu_VerificaSpecificheButton = uibutton(app.Tab, 'push');
            app.mu_VerificaSpecificheButton.ButtonPushedFcn = createCallbackFcn(app, @mu_VerificaSpecificheButtonPushed, true);
            app.mu_VerificaSpecificheButton.Text = 'Verifica specifiche';
            app.mu_RSLabel = uilabel(app.Tab);
            app.mu_RSLabel.Text = '';
            app.mu_NPLabel = uilabel(app.Tab);
            app.mu_NPLabel.Text = '';
            app.mu_RPLabel = uilabel(app.Tab);
            app.mu_RPLabel.Text = '';
            app.mu_VerificadelleSpecificheLabel = uilabel(app.Tab);
            app.mu_VerificadelleSpecificheLabel.Text = 'Verifica delle specifiche';
            app.mu_AnalisidiRobustaStabilitLabel2 = uilabel(app.Tab);
            app.mu_AnalisidiRobustaStabilitLabel2.Text = 'Nominale e Robusta Prestazione';
            app.mu_FunzioniPesoLabel = uilabel(app.Tab);
            app.mu_FunzioniPesoLabel.Text = 'Visualizza la risposta in frequenza delle funzioni peso';
            app.mu_FunzioniPesoButton = uibutton(app.Tab, 'push');
            app.mu_FunzioniPesoButton.ButtonPushedFcn = createCallbackFcn(app, @mu_FunzioniPesoButtonPushed, true);
            app.mu_FunzioniPesoButton.Text = 'Funzioni peso';
            app.Hinf_NSLamp_2Label_2 = uilabel(app.Tab);
            app.Hinf_NSLamp_2Label_2.Text = 'Nominale Stabilità';
            app.mu_NSLamp = uilamp(app.Tab);
            app.Hinf_RSLamp_2Label_2 = uilabel(app.Tab);
            app.Hinf_RSLamp_2Label_2.Text = 'Robusta Stabilità';
            app.mu_RSLamp = uilamp(app.Tab);
            app.Hinf_NPLamp_2Label_2 = uilabel(app.Tab);
            app.Hinf_NPLamp_2Label_2.Text = 'Nominale Prestazione';
            app.mu_NPLamp = uilamp(app.Tab);
            app.Hinf_RPLamp_2Label_2 = uilabel(app.Tab);
            app.Hinf_RPLamp_2Label_2.Text = 'Robusta Prestazione';
            app.mu_RPLamp = uilamp(app.Tab);
            app.LSDP_OrdinedelcontrolloreEditField_2Label = uilabel(app.Tab);
            app.LSDP_OrdinedelcontrolloreEditField_2Label.Text = 'Ordine del controllore';
            app.mu_OrdinedelcontrolloreEditField = uieditfield(app.Tab, 'numeric');
            app.ClassicoTab_2 = uitab(app.TabGroup);
            app.ClassicoTab_2.Title = 'Classico';
            app.ClassicoTab_2.Units = 'pixels';
            app.TextArea_6Label = uilabel(app.ClassicoTab_2);
            app.TextArea_6Label.Text = '';
            app.TextArea_6 = uitextarea(app.ClassicoTab_2);
            app.TextArea_6.Value = {'Questa sezione carica un controllore PID, precedentemente sintetizzato in maniera manuale usando il comando Matlab ''pidTuner''.'};
            app.SintesidelcontrolloreLabel_7 = uilabel(app.ClassicoTab_2);
            app.SintesidelcontrolloreLabel_7.Text = 'Sintesi del controllore';
            app.SintesiPIDButton = uibutton(app.ClassicoTab_2, 'push');
            app.SintesiPIDButton.ButtonPushedFcn = createCallbackFcn(app, @SintesiPIDButtonPushed, true);
            app.SintesiPIDButton.Text = 'Sintesi PID';
            app.ImpostaiseguentiparametriLabel_2 = uilabel(app.ClassicoTab_2);
            app.ImpostaiseguentiparametriLabel_2.Text = 'Imposta i seguenti parametri';
            app.AnalisidiRobustaStabilitLabel_12 = uilabel(app.ClassicoTab_2);
            app.AnalisidiRobustaStabilitLabel_12.Text = 'Analisi di Robusta Stabilità, Prestazione Nominale e Robusta Prestazione';
            app.RobustAnalysisButton_5 = uibutton(app.ClassicoTab_2, 'push');
            app.RobustAnalysisButton_5.ButtonPushedFcn = createCallbackFcn(app, @RobustAnalysisButton_5Pushed, true);
            app.RobustAnalysisButton_5.Text = 'Robust Analysis';
            app.AnalisidellarispostainfrequenzaimpiantononscalatoLabel_12 = uilabel(app.ClassicoTab_2);
            app.AnalisidellarispostainfrequenzaimpiantononscalatoLabel_12.Text = 'Analisi della risposta in frequenza (impianto perturbato non scalato)';
            app.FrequencyResponseButton_5 = uibutton(app.ClassicoTab_2, 'push');
            app.FrequencyResponseButton_5.ButtonPushedFcn = createCallbackFcn(app, @FrequencyResponseButton_5Pushed, true);
            app.FrequencyResponseButton_5.Text = 'Frequency Response';
            app.AnalisidellarispostatemporaleimpiantoperturbatoscalatoLabel_5 = uilabel(app.ClassicoTab_2);
            app.AnalisidellarispostatemporaleimpiantoperturbatoscalatoLabel_5.Text = 'Analisi della risposta temporale (impianto perturbato scalato)';
            app.TimeResponseButton_5 = uibutton(app.ClassicoTab_2, 'push');
            app.TimeResponseButton_5.ButtonPushedFcn = createCallbackFcn(app, @TimeResponseButton_5Pushed, true);
            app.TimeResponseButton_5.Text = 'Time Response';
            app.radsLabel = uilabel(app.ClassicoTab_2);
            app.radsLabel.Text = 'rad/s';
            app.Label = uilabel(app.ClassicoTab_2);
            app.Label.Text = '%';
            app.dBLabel = uilabel(app.ClassicoTab_2);
            app.dBLabel.Text = 'dB';
            app.msLabel = uilabel(app.ClassicoTab_2);
            app.msLabel.Text = 'ms';
            app.VerificheButton_4 = uibutton(app.ClassicoTab_2, 'push');
            app.VerificheButton_4.ButtonPushedFcn = createCallbackFcn(app, @VerificheButton_4Pushed, true);
            app.VerificheButton_4.Text = 'Verifiche';
            app.AMPEditFieldLabel = uilabel(app.ClassicoTab_2);
            app.AMPEditFieldLabel.Text = 'AMP';
            app.AMPEditField = uieditfield(app.ClassicoTab_2, 'numeric');
            app.STEditFieldLabel = uilabel(app.ClassicoTab_2);
            app.STEditFieldLabel.Text = 'ST';
            app.STEditField = uieditfield(app.ClassicoTab_2, 'numeric');
            app.OrdinedelcontrolloreEditField_14Label = uilabel(app.ClassicoTab_2);
            app.OrdinedelcontrolloreEditField_14Label.Text = 'Ordine del controllore';
            app.OrdinedelcontrolloreEditField_14 = uieditfield(app.ClassicoTab_2, 'numeric');
            app.NominaleStabilitEditField_14Label = uilabel(app.ClassicoTab_2);
            app.NominaleStabilitEditField_14Label.Text = 'Nominale Stabilità';
            app.NominaleStabilitEditField_14 = uieditfield(app.ClassicoTab_2, 'text');
            app.WcEditField = uieditfield(app.ClassicoTab_2, 'numeric');
            app.OSEditField = uieditfield(app.ClassicoTab_2, 'numeric');
            app.PID_SintesiButton = uibutton(app.ClassicoTab_2, 'push');
            app.PID_SintesiButton.ButtonPushedFcn = createCallbackFcn(app, @PID_SintesiButtonPushed, true);
            app.PID_SintesiButton.Text = 'Sintesi PID';
            app.PID_AnalisidiRobustaStabilitLabel = uilabel(app.ClassicoTab_2);
            app.PID_AnalisidiRobustaStabilitLabel.Text = 'Analisi di Robusta Stabilità, Prestazione';
            app.PID_RobustAnalysisButton = uibutton(app.ClassicoTab_2, 'push');
            app.PID_RobustAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PID_RobustAnalysisButtonPushed, true);
            app.PID_RobustAnalysisButton.Text = 'Analisi robusta';
            app.PID_AnalisidellarispostainfrequenzaimpiantononscalatoLabel = uilabel(app.ClassicoTab_2);
            app.PID_AnalisidellarispostainfrequenzaimpiantononscalatoLabel.Text = 'Analisi della risposta in frequenza (impianto perturbato non scalato)';
            app.PID_RispostainfrequenzaButton = uibutton(app.ClassicoTab_2, 'push');
            app.PID_RispostainfrequenzaButton.ButtonPushedFcn = createCallbackFcn(app, @PID_RispostainfrequenzaButtonPushed, true);
            app.PID_RispostainfrequenzaButton.Text = 'Risposta in frequenza';
            app.PID_AnalisidellarispostatemporaleimpiantoLabel = uilabel(app.ClassicoTab_2);
            app.PID_AnalisidellarispostatemporaleimpiantoLabel.Text = 'Analisi della risposta temporale (impianto perturbato scalato)';
            app.PID_RispostaTemporaleButton = uibutton(app.ClassicoTab_2, 'push');
            app.PID_RispostaTemporaleButton.ButtonPushedFcn = createCallbackFcn(app, @PID_RispostaTemporaleButtonPushed, true);
            app.PID_RispostaTemporaleButton.Text = 'Risposta Temporale';
            app.PID_VerificaSpecificheButton = uibutton(app.ClassicoTab_2, 'push');
            app.PID_VerificaSpecificheButton.ButtonPushedFcn = createCallbackFcn(app, @PID_VerificaSpecificheButtonPushed, true);
            app.PID_VerificaSpecificheButton.Text = 'Verifica specifiche';
            app.PID_VerificadelleSpecificheLabel = uilabel(app.ClassicoTab_2);
            app.PID_VerificadelleSpecificheLabel.Text = 'Verifica delle specifiche';
            app.PID_AnalisidiRobustaStabilitLabel2 = uilabel(app.ClassicoTab_2);
            app.PID_AnalisidiRobustaStabilitLabel2.Text = 'Nominale e Robusta Prestazione';
            app.mu_NSLamp_2Label = uilabel(app.ClassicoTab_2);
            app.mu_NSLamp_2Label.Text = 'Nominale Stabilità';
            app.PID_NSLamp = uilamp(app.ClassicoTab_2);
            app.mu_RSLamp_2Label = uilabel(app.ClassicoTab_2);
            app.mu_RSLamp_2Label.Text = 'Robusta Stabilità';
            app.PID_RSLamp = uilamp(app.ClassicoTab_2);
            app.mu_NPLamp_2Label = uilabel(app.ClassicoTab_2);
            app.mu_NPLamp_2Label.Text = 'Nominale Prestazione';
            app.PID_NPLamp = uilamp(app.ClassicoTab_2);
            app.mu_RPLamp_2Label = uilabel(app.ClassicoTab_2);
            app.mu_RPLamp_2Label.Text = 'Robusta Prestazione';
            app.PID_RPLamp = uilamp(app.ClassicoTab_2);
            app.mu_OrdinedelcontrolloreEditField_2Label = uilabel(app.ClassicoTab_2);
            app.mu_OrdinedelcontrolloreEditField_2Label.Text = 'Ordine del controllore';
            app.PID_OrdinedelcontrolloreEditField = uieditfield(app.ClassicoTab_2, 'numeric');
            app.PID_RSLabel = uilabel(app.ClassicoTab_2);
            app.PID_RSLabel.Text = '';
            app.PID_NPLabel = uilabel(app.ClassicoTab_2);
            app.PID_NPLabel.Text = '';
            app.PID_RPLabel = uilabel(app.ClassicoTab_2);
            app.PID_RPLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @ParameterDefinition)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end